classdef Snake_2d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Snake2dUIFigure       matlab.ui.Figure
        ZoomSlider            matlab.ui.control.Slider
        ZoomSliderLabel       matlab.ui.control.Label
        ContinueButton        matlab.ui.control.Button
        PauseLabel            matlab.ui.control.Label
        TimerLabel            matlab.ui.control.Label
        Message               matlab.ui.control.Label
        PointsEditField       matlab.ui.control.NumericEditField
        PointsEditFieldLabel  matlab.ui.control.Label
        StartButton           matlab.ui.control.Button
        SpeedEditField        matlab.ui.control.NumericEditField
        SpeedEditFieldLabel   matlab.ui.control.Label
        HeightEditField       matlab.ui.control.NumericEditField
        HeightLabel           matlab.ui.control.Label
        LengthEditField       matlab.ui.control.NumericEditField
        LengthLabel           matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signs_dir={'>' '<' '^' 'v'}
        dir_xy=[1 0;-1 0;0 1;0 -1];
        food_amount=50;
        CUR_DIRECT=randi(4);
        width_snake=3;
        PAUSE=0;
        food_colors={'#0072BD' 	'#D95319' 	'#EDB120' 	'#7E2F8E' 	'#77AC30' 	'#4DBEEE' 	'#A2142F'};
        food_shape={'o' '+' '*' 'x' 's' 'd' 'p' 'h'};
        current_food_color
        current_food_shape
        food_num
        food_fig
        snake_head
        snake_tail
        head_p
        head_num
        tail_p
        tail_num
    end
    
    methods (Access = private)
        
        function update_borders(app)
            cla(app.UIAxes);
            x=app.LengthEditField.Value;
            y=app.HeightEditField.Value;
            app.UIAxes.XLim=[-2 x+2];
            app.UIAxes.YLim=[-2 y+2];
            eps=0.5;
            plot(app.UIAxes,[-eps x+eps],[0 0]-eps,'Color',[0 0 0],"LineWidth",3);   hold(app.UIAxes,'on');
            plot(app.UIAxes,[-eps x+eps],[y+eps y+eps],'Color',[0 0 0],"LineWidth",3);   hold(app.UIAxes,'on');
            plot(app.UIAxes,[0 0]-eps,[-eps y+eps],'Color',[0 0 0],"LineWidth",3);   hold(app.UIAxes,'on');
            plot(app.UIAxes,[x x]+eps,[-eps y+eps],'Color',[0 0 0],"LineWidth",3);   hold(app.UIAxes,'on');
        end       
        
        function p=num_2_p(app,num)
            y=app.HeightEditField.Value;
            p(1)=floor(num/(y+1));
            p(2)=num-p(1)*(y+1);
        end
        
        function num=p_2_num(app,p)
            y=app.HeightEditField.Value;
            num=p(1)*(y+1)+p(2);
        end
        
        function loose=check_end(app,p,tail_num)
            x=app.LengthEditField.Value;
            y=app.HeightEditField.Value;
            if p(1)<0 || p(2)<0 || p(1)>x || p(2)>y ...
                    || length(tail_num)~=length(unique(tail_num))
                loose=1;
            else
                loose=0;
            end
            
        end
        
        function [food_num,food_fig]=add_food(app,tail_num,food_num,anoumt)
            x=app.LengthEditField.Value;
            y=app.HeightEditField.Value;
            options=0:((x+1)*(y+1)-1);
            lim_num=[tail_num ; food_num];
            for j=1:length(tail_num)
                options=options(options~=lim_num(j));
            end
            for f=1:anoumt                                
                next_food=randi(length(options));
                food_num(end+1,1)=options(next_food);
                options(next_food)=[];
            end
            for j=1:length(food_num)
                food_p(j,:)=app.num_2_p(food_num(j));
            end
            if randi(100)<=10
                app.current_food_shape=app.food_shape{randi(length(app.food_shape))};
                app.current_food_color=app.food_colors{randi(length(app.food_colors))};
            end
            hold(app.UIAxes,'on');
            food_fig=plot(app.UIAxes,food_p(:,1),food_p(:,2),'LineStyle','none','Marker',...
                    app.current_food_shape,'Color',app.current_food_color,'LineWidth',2.5);
        end
        
        function [food_num,food_fig,head_p,head_fig,tail_fig]=start_game(app)
            cla(app.UIAxes);
            app.Message.Visible='off';
            app.ContinueButton.Enable='on';
            app.update_borders();            
            app.PointsEditField.Value=app.SpeedEditField.Value;
            app.CUR_DIRECT=randi(length(app.signs_dir));
            x=app.LengthEditField.Value;
            y=app.HeightEditField.Value;
            app.head_num=randi((x+1)*(y+1))-1;
            head_p=app.num_2_p(app.head_num);
            app.tail_num=app.head_num;
            app.tail_p=head_p;
            hold(app.UIAxes,'on');
            head_fig=plot(app.UIAxes,head_p(1),head_p(2),'k','Marker',...
                app.signs_dir{app.CUR_DIRECT},'LineWidth',2.5);
            hold(app.UIAxes,'on');
            tail_fig=plot(app.UIAxes,app.tail_p(:,1),app.tail_p(:,2),'k','LineWidth',app.width_snake);
            [food_num,food_fig]=app.add_food(app.tail_num,[],app.food_amount);                  
            app.TimerLabel.Visible='on';
            for t=4:-1:1
                app.TimerLabel.Text=num2str(t);
                pause(1);
            end
            app.TimerLabel.Visible='off';
        end
        
        function run_game(app)
            speed=app.SpeedEditField.Value;
            END_GAME=0;
            while ~END_GAME
                if app.PAUSE==1
                    app.PauseLabel.Visible='on';
                    break;
                end

                app.PauseLabel.Visible='off';
                pause(1*speed^-1);
                                
                app.head_p=app.head_p+app.dir_xy(app.CUR_DIRECT,:);
                app.head_num=app.p_2_num(app.head_p);
                
                if ~isempty(find(app.food_num==app.head_num, 1))
                    app.PointsEditField.Value=app.PointsEditField.Value+speed;
                    app.food_num=app.food_num(app.food_num~=app.head_num);
                    delete(app.food_fig);
                    [app.food_num,app.food_fig]=app.add_food(app.tail_num,app.food_num,1);
                    
                    app.tail_p=[app.head_p ; app.tail_p];
                    app.tail_num=[app.head_num ; app.tail_num]; 
                else
                    app.tail_p=[app.head_p ; app.tail_p(1:end-1,:)];
                    app.tail_num=[app.head_num ; app.tail_num(1:end-1,:)];  
                end
                
                             
                delete(app.snake_head);
                delete(app.snake_tail);
                app.snake_head=plot(app.UIAxes,app.head_p(1),app.head_p(2),'k','Marker',...
                    app.signs_dir{app.CUR_DIRECT},'LineWidth',2.5);
                app.snake_tail=plot(app.UIAxes,app.tail_p(:,1),app.tail_p(:,2),'k','LineWidth',app.width_snake);
                if app.check_end(app.head_p,app.tail_num)
                    app.Message.Visible='on';
                    app.ContinueButton.Enable='off';
                    END_GAME=1;
                end
                if  app.ZoomSlider.Value<98                    
                    factor=1;
                    r=factor*((app.ZoomSlider.Value+20)/100)*...
                    max(app.LengthEditField.Value,app.HeightEditField.Value);
                    app.UIAxes.XLim=[app.head_p(1)-r/2 app.head_p(1)+r/2];
                    app.UIAxes.YLim=[app.head_p(2)-r/2 app.head_p(2)+r/2];
                else
                    app.UIAxes.XLim=[-0.5 app.LengthEditField.Value+0.5];
                    app.UIAxes.YLim=[-0.5 app.HeightEditField.Value+0.5];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.update_borders();
            app.current_food_shape=app.food_shape{randi(length(app.food_shape))};
            app.current_food_color=app.food_colors{randi(length(app.food_colors))};
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            app.update_borders();
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.update_borders();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            [app.food_num,app.food_fig,app.head_p,app.snake_head,app.snake_tail]=app.start_game();
            app.tail_p=app.head_p;
            app.tail_num=app.p_2_num(app.tail_p);
            app.ContinueButtonPushed();
        end

        % Window key press function: Snake2dUIFigure
        function Snake2dUIFigureWindowKeyPress(app, event)
           pause(0.05);
           switch event.Key
                case 'rightarrow'
                    if app.CUR_DIRECT~=2
                        app.CUR_DIRECT=1;
                    end
                case 'leftarrow'
                    if app.CUR_DIRECT~=1
                        app.CUR_DIRECT=2;
                    end
                case 'uparrow'
                    if app.CUR_DIRECT~=4
                        app.CUR_DIRECT=3;
                    end
                case 'downarrow'
                    if app.CUR_DIRECT~=3
                        app.CUR_DIRECT=4;
                    end
                    
                case 'd'
                    if app.CUR_DIRECT~=2
                        app.CUR_DIRECT=1;
                    end
                case 'a'
                    if app.CUR_DIRECT~=1
                        app.CUR_DIRECT=2;
                    end
                case 'w'
                    if app.CUR_DIRECT~=4
                        app.CUR_DIRECT=3;
                    end
                case 's'
                    if app.CUR_DIRECT~=3
                        app.CUR_DIRECT=4;
                    end
                    
                case 'p'
                    if ~app.PAUSE
                        app.PAUSE=1;
                    else
                        app.PAUSE=0;
                        app.run_game();
                    end                                        
                otherwise                    
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.PAUSE=0;
            app.run_game();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Snake2dUIFigure = uifigure('Visible', 'off');
            app.Snake2dUIFigure.Name = 'Snake 2d';
            app.Snake2dUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @Snake2dUIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.Snake2dUIFigure);
            title(app.UIAxes, 'Snake')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.LengthLabel = uilabel(app.Snake2dUIFigure);
            app.LengthLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.Snake2dUIFigure, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditField.Value = 30;
            app.HeightLabel = uilabel(app.Snake2dUIFigure);
            app.HeightLabel.Text = 'Height :';
            app.HeightEditField = uieditfield(app.Snake2dUIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 30;
            app.SpeedEditFieldLabel = uilabel(app.Snake2dUIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed :';
            app.SpeedEditField = uieditfield(app.Snake2dUIFigure, 'numeric');
            app.SpeedEditField.Value = 12;
            app.StartButton = uibutton(app.Snake2dUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PointsEditFieldLabel = uilabel(app.Snake2dUIFigure);
            app.PointsEditFieldLabel.Text = 'Points :';
            app.PointsEditField = uieditfield(app.Snake2dUIFigure, 'numeric');
            app.Message = uilabel(app.Snake2dUIFigure);
            app.Message.Visible = 'off';
            app.Message.Text = 'You Lose !!!';
            app.TimerLabel = uilabel(app.Snake2dUIFigure);
            app.TimerLabel.Text = 'Timer';
            app.PauseLabel = uilabel(app.Snake2dUIFigure);
            app.PauseLabel.Visible = 'off';
            app.PauseLabel.Text = 'Pause';
            app.ContinueButton = uibutton(app.Snake2dUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ZoomSliderLabel = uilabel(app.Snake2dUIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.Snake2dUIFigure);
            app.ZoomSlider.Value = 100;
            % Show the figure after all components are created
            app.Snake2dUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Snake_2d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Snake2dUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Snake2dUIFigure)
        end
    end
end